classdef appGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        BaseSongPanel            matlab.ui.container.Panel
        MusicFileEditFieldLabel  matlab.ui.control.Label
        MusicFileEditField       matlab.ui.control.EditField
        BrowseButton             matlab.ui.control.Button
        AnalyzeButton            matlab.ui.control.Button
        KeyTitleLabel            matlab.ui.control.Label
        KeyLabel                 matlab.ui.control.Label
        WorkingLabel             matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [filename,filepath] = uigetfile('*');
            file = strcat(filepath,filename);
            app.MusicFileEditField.Value = file;
            app.AnalyzeButton.Enable = 'on';
        end

        % Value changed function: MusicFileEditField
        function MusicFileEditFieldValueChanged(app, event)
            value = app.MusicFileEditField.Value;
            if value
                app.AnalyzeButton.Enable = 'on';
            else
                app.AnalyzeButton.Enable = 'off';
            end
        end

        % Value changing function: MusicFileEditField
        function MusicFileEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.MusicFileEditField.Value = changingValue;
            if changingValue
                app.AnalyzeButton.Enable = 'on';
            else
                app.AnalyzeButton.Enable = 'off';
            end
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            app.AnalyzeButton.Enable = 'off';
            app.WorkingLabel.Text = 'Working...';
            
            file = app.MusicFileEditField.Value;
            histo = noteHisto(file);
            notes = categorical({'A', 'A♯ / B♭', 'B', 'C', 'C♯ / D♭', 'D',...
                'D♯ / E♭', 'E', 'F', 'F♯ / G♭', 'G', 'G♯ / A♭'});
            % set up "ideal" profiles, indexed by key (note) num
            profiles = zeros(12);
            profiles(:,1) = transpose([3 0 1 0 1 2 0 2 0 2 0 1]);
            
            for i1 = 1:11
                profiles(:,i1+1) = wshift('1D',profiles(:,1),-i1);
            end
            
            % sort the histo values for analysis
            [~, histoIndices] = sort(histo,'descend');
            
            key = keyGuess(histoIndices, profiles);
            keyString = notes(key);
            app.KeyLabel.Text = char(keyString);
            
            app.AnalyzeButton.Enable = 'on';
            app.WorkingLabel.Text = '';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.BaseSongPanel = uipanel(app.UIFigure);
            app.BaseSongPanel.Title = 'Base Song';
            app.MusicFileEditFieldLabel = uilabel(app.BaseSongPanel);
            app.MusicFileEditFieldLabel.Text = 'Music File';
            app.MusicFileEditField = uieditfield(app.BaseSongPanel, 'text');
            app.MusicFileEditField.ValueChangedFcn = createCallbackFcn(app, @MusicFileEditFieldValueChanged, true);
            app.MusicFileEditField.ValueChangingFcn = createCallbackFcn(app, @MusicFileEditFieldValueChanging, true);
            app.BrowseButton = uibutton(app.BaseSongPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse...';
            app.AnalyzeButton = uibutton(app.BaseSongPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Enable = 'off';
            app.AnalyzeButton.Text = 'Analyze';
            app.KeyTitleLabel = uilabel(app.BaseSongPanel);
            app.KeyTitleLabel.Text = 'Key:';
            app.KeyLabel = uilabel(app.BaseSongPanel);
            app.KeyLabel.Text = '';
            app.WorkingLabel = uilabel(app.BaseSongPanel);
            app.WorkingLabel.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = appGui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end