classdef appGui_temp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Song1Panel                 matlab.ui.container.Panel
        MusicFileEditFieldLabel    matlab.ui.control.Label
        MusicFileEditField         matlab.ui.control.EditField
        BrowseButton               matlab.ui.control.Button
        AnalyzeButton              matlab.ui.control.Button
        KeyTitleLabel              matlab.ui.control.Label
        KeyLabel                   matlab.ui.control.Label
        WorkingLabel               matlab.ui.control.Label
        TempoTitleLabel            matlab.ui.control.Label
        TempoLabel                 matlab.ui.control.Label
        Song2Panel                 matlab.ui.container.Panel
        MusicFileEditField_2Label  matlab.ui.control.Label
        MusicFileEditField_2       matlab.ui.control.EditField
        BrowseButton_2             matlab.ui.control.Button
        AnalyzeButton_2            matlab.ui.control.Button
        KeyTitleLabel_2            matlab.ui.control.Label
        KeyLabel_2                 matlab.ui.control.Label
        WorkingLabel_2             matlab.ui.control.Label
        TempoTitleLabel_2          matlab.ui.control.Label
        TempoLabel_2               matlab.ui.control.Label
        MatchButton                matlab.ui.control.Button
        MatchWaitingLabel          matlab.ui.control.Label
    end


    properties (Access = private)
        Song1Data % Song1 data
        Song1Fs   % Song1 samp rate
        Song1Key  % Song1 key num
        Song1BPM  % Song1 tempo
        Song2Data % Song2 data
        Song2Fs   % Song2 samp rate
        Song2Key  % Song2 key num
        Song2BPM  % Song2 tempo
    end


    methods (Access = private)

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            app.AnalyzeButton.Enable = 'off';
            app.WorkingLabel.Text = 'Working...';
            
            file = app.MusicFileEditField.Value;
            [app.Song1Data, app.Song1Fs] = audioread(file);
            histo = noteHisto(app.Song1Data, app.Song1Fs);
            notes = categorical({'A', 'A♯ / B♭', 'B', 'C', 'C♯ / D♭', 'D',...
                'D♯ / E♭', 'E', 'F', 'F♯ / G♭', 'G', 'G♯ / A♭'});
            % set up "ideal" profiles, indexed by key (note) num
            profiles = zeros(12);
            profiles(:,1) = transpose([3 0 1 0 1 2 0 2 0 2 0 1]);
            
            for i1 = 1:11
                profiles(:,i1+1) = wshift('1D',profiles(:,1),-i1);
            end
            
            % sort the histo values for analysis
            [~, histoIndices] = sort(histo,'descend');
            
            key = keyGuess(histoIndices, profiles);
            app.Song1Key = key;
            keyString = notes(key);
            app.KeyLabel.Text = char(keyString);
            
            app.Song1BPM = bpm(file);
            app.TempoLabel.Text = num2str(app.Song1BPM);
            
            app.AnalyzeButton.Enable = 'on';
            app.WorkingLabel.Text = '';
        end

        % Button pushed function: AnalyzeButton_2
        function AnalyzeButton_2Pushed(app, event)
            app.AnalyzeButton_2.Enable = 'off';
            app.WorkingLabel_2.Text = 'Working...';
            
            file = app.MusicFileEditField_2.Value;
            [app.Song2Data, app.Song2Fs] = audioread(file);
            histo = noteHisto(app.Song2Data, app.Song2Fs);
            notes = categorical({'A', 'A♯ / B♭', 'B', 'C', 'C♯ / D♭', 'D',...
                'D♯ / E♭', 'E', 'F', 'F♯ / G♭', 'G', 'G♯ / A♭'});
            % set up "ideal" profiles, indexed by key (note) num
            profiles = zeros(12);
            profiles(:,1) = transpose([3 0 1 0 1 2 0 2 0 2 0 1]);
            
            for i1 = 1:11
                profiles(:,i1+1) = wshift('1D',profiles(:,1),-i1);
            end
            
            % sort the histo values for analysis
            [~, histoIndices] = sort(histo,'descend');
            
            key = keyGuess(histoIndices, profiles);
            app.Song2Key = key;
            keyString = notes(key);
            app.KeyLabel_2.Text = char(keyString);
            
            app.Song2BPM = bpm(file);
            app.TempoLabel_2.Text = num2str(app.Song2BPM);
            
            app.AnalyzeButton_2.Enable = 'on';
            app.WorkingLabel_2.Text = '';
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [filename,filepath] = uigetfile('*');
            file = strcat(filepath,filename);
            app.MusicFileEditField.Value = file;
            app.AnalyzeButton.Enable = 'on';
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            [filename,filepath] = uigetfile('*');
            file = strcat(filepath,filename);
            app.MusicFileEditField_2.Value = file;
            app.AnalyzeButton_2.Enable = 'on';
        end

        % Button pushed function: MatchButton
        function MatchButtonPushed(app, event)
            app.MatchButton.Enable = 'off';
            app.MatchWaitingLabel.Text = 'Waiting...';
            
            app.Song2Data = phase_vocode(app.Song2Data,app.Song1BPM/app.Song2BPM);
            %Song2SpeedChange = phase_vocode(app.Song2Data,app.Song1BPM/app.Song2BPM);
            
            histo = noteHisto(app.Song2Data, app.Song2Fs);
            %histo = noteHisto(Song2SpeedChange, app.Song2Fs);
            
            % set up "ideal" profiles, indexed by key (note) num
            profiles = zeros(12);
            profiles(:,1) = transpose([3 0 1 0 1 2 0 2 0 2 0 1]);
            
            for i1 = 1:11
                profiles(:,i1+1) = wshift('1D',profiles(:,1),-i1);
            end
            
            % sort the histo values for analysis
            [~, histoIndices] = sort(histo,'descend');
            
            newKey = keyGuess(histoIndices, profiles);
            
            shiftAmount = app.Song1Key - newKey;
            if abs(shiftAmount) > 6
                shiftAmount = sign(-shiftAmount)*(12 - abs(shiftAmount));
            end
            app.Song2Data = pitch_shift(app.Song2Data, 1-(shiftAmount*0.05));
            %Song2Shift = pitch_shift(app.Song2Data, 1-(shiftAmount*0.05));
            
            app.MatchButton.Enable = 'on';
            app.MatchWaitingLabel.Text = '';
            
            [filename,filepath] = uiputfile('*');
            file = strcat(filepath,filename);
            
            audiowrite(file, app.Song2Data,app.Song2Fs)
            soundsc(app.Song2Data, app.Song2Fs);
        end

        % Value changing function: MusicFileEditField
        function MusicFileEditFieldValueChanged(app, event)
            value = app.MusicFileEditField.Value;
            if value
                app.AnalyzeButton.Enable = 'on';
            else
                app.AnalyzeButton.Enable = 'off';
            end
        end

        % Value changing function: MusicFileEditField
        function MusicFileEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.MusicFileEditField.Value = changingValue;
            if changingValue
                app.AnalyzeButton.Enable = 'on';
            else
                app.AnalyzeButton.Enable = 'off';
            end
        end

        % Value changed function: MusicFileEditField_2
        function MusicFileEditField_2ValueChanged(app, event)
            value = app.MusicFileEditField_2.Value;
                if value
                app.AnalyzeButton_2.Enable = 'on';
            else
                app.AnalyzeButton_2.Enable = 'off';
            end
        end

        % Value changing function: MusicFileEditField_2
        function MusicFileEditField_2ValueChanging(app, event)
            changingValue = event.Value;
            app.MusicFileEditField_2.Value = changingValue;
            if changingValue
                app.AnalyzeButton_2.Enable = 'on';
            else
                app.AnalyzeButton_2.Enable = 'off';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Song1Panel = uipanel(app.UIFigure);
            app.Song1Panel.Title = 'Song 1';
            app.MusicFileEditFieldLabel = uilabel(app.Song1Panel);
            app.MusicFileEditFieldLabel.Text = 'Music File';
            app.MusicFileEditField = uieditfield(app.Song1Panel, 'text');
            app.MusicFileEditField.ValueChangedFcn = createCallbackFcn(app, @MusicFileEditFieldValueChanged, true);
            app.MusicFileEditField.ValueChangingFcn = createCallbackFcn(app, @MusicFileEditFieldValueChanging, true);
            app.BrowseButton = uibutton(app.Song1Panel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse...';
            app.AnalyzeButton = uibutton(app.Song1Panel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Enable = 'off';
            app.AnalyzeButton.Text = 'Analyze';
            app.KeyTitleLabel = uilabel(app.Song1Panel);
            app.KeyTitleLabel.Text = 'Key:';
            app.KeyLabel = uilabel(app.Song1Panel);
            app.KeyLabel.Text = '';
            app.WorkingLabel = uilabel(app.Song1Panel);
            app.WorkingLabel.Text = '';
            app.TempoTitleLabel = uilabel(app.Song1Panel);
            app.TempoTitleLabel.Text = 'Tempo:';
            app.TempoLabel = uilabel(app.Song1Panel);
            app.TempoLabel.Text = '';
            app.Song2Panel = uipanel(app.UIFigure);
            app.Song2Panel.Title = 'Song 2';
            app.MusicFileEditField_2Label = uilabel(app.Song2Panel);
            app.MusicFileEditField_2Label.Text = 'Music File';
            app.MusicFileEditField_2 = uieditfield(app.Song2Panel, 'text');
            app.MusicFileEditField_2.ValueChangedFcn = createCallbackFcn(app, @MusicFileEditField_2ValueChanged, true);
            app.MusicFileEditField_2.ValueChangingFcn = createCallbackFcn(app, @MusicFileEditField_2ValueChanging, true);
            app.BrowseButton_2 = uibutton(app.Song2Panel, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse...';
            app.AnalyzeButton_2 = uibutton(app.Song2Panel, 'push');
            app.AnalyzeButton_2.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButton_2Pushed, true);
            app.AnalyzeButton_2.Enable = 'off';
            app.AnalyzeButton_2.Text = 'Analyze';
            app.KeyTitleLabel_2 = uilabel(app.Song2Panel);
            app.KeyTitleLabel_2.Text = 'Key:';
            app.KeyLabel_2 = uilabel(app.Song2Panel);
            app.KeyLabel_2.Text = '';
            app.WorkingLabel_2 = uilabel(app.Song2Panel);
            app.WorkingLabel_2.Text = '';
            app.TempoTitleLabel_2 = uilabel(app.Song2Panel);
            app.TempoTitleLabel_2.Text = 'Tempo:';
            app.TempoLabel_2 = uilabel(app.Song2Panel);
            app.TempoLabel_2.Text = '';
            app.MatchButton = uibutton(app.UIFigure, 'push');
            app.MatchButton.ButtonPushedFcn = createCallbackFcn(app, @MatchButtonPushed, true);
            app.MatchButton.Text = 'Match';
            app.MatchWaitingLabel = uilabel(app.UIFigure);
            app.MatchWaitingLabel.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = appGui_temp()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end